classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        CaptureImageButton         matlab.ui.control.Button
        UploadImageButton          matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
        NewImageButton             matlab.ui.control.Button
        ConversionLabel            matlab.ui.control.Label
        TrainedConversionButton    matlab.ui.control.Button
        UntrainedConversionButton  matlab.ui.control.Button
        SwitchLabel                matlab.ui.control.Label
        Switch                     matlab.ui.control.ToggleSwitch
    end

    
    properties (Access = private)
        webcamObject; % Description
        imageObject;
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.webcamObject = webcam;
            app.imageObject = image(app.UIAxes);
            axis(app.UIAxes,'ij')
            res = split(app.webcamObject.Resolution,'x');
            app.UIAxes.XLim = [0,str2double(res{1})];
            app.UIAxes.YLim = [0,str2double(res{2})];
            app.webcamObject.preview(app.imageObject);
        end

        % Button pushed function: UploadImageButton
        function UploadImageButtonPushed(app, event)
            delete(app.webcamObject);
            delete(app.imageObject);
            [filename, directory] = uigetfile('*.png;*.jpg;*.bmp;*.tif');
            complete_name = fullfile(directory, filename);
            TheImage = imread(complete_name);
            imshow(TheImage, 'parent', app.UIAxes);
        end

        % Button pushed function: CaptureImageButton
        function CaptureImageButtonPushed(app, event)
            img=snapshot(app.webcamObject);
            imwrite(img, 'img.jpg');
            %img=image(app.imageObject);
            %imwrite(img,'img.jpg');
            %res = split(img.Resolution,'x');
            %app.UIAxes.XLim = [0,str2double(xR)];
            %app.UIAxes.YLim = [0,str2double(yR)];
            imshow(img, 'parent', app.UIAxes);
            delete(app.webcamObject);
            delete(app.imageObject);
        end

        % Button pushed function: NewImageButton
        function NewImageButtonPushed(app, event)
            app.webcamObject = webcam;
            app.imageObject = image(app.UIAxes);
            axis(app.UIAxes,'ij')
            res = split(app.webcamObject.Resolution,'x');
            app.UIAxes.XLim = [0,str2double(res{1})];
            app.UIAxes.YLim = [0,str2double(res{2})];
            app.webcamObject.preview(app.imageObject);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.CaptureImageButton = uibutton(app.UIFigure, 'push');
            app.CaptureImageButton.ButtonPushedFcn = createCallbackFcn(app, @CaptureImageButtonPushed, true);
            app.CaptureImageButton.Text = 'Capture Image';
            app.UploadImageButton = uibutton(app.UIFigure, 'push');
            app.UploadImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadImageButtonPushed, true);
            app.UploadImageButton.Text = 'Upload Image';
            app.UIAxes = uiaxes(app.UIFigure);
            app.NewImageButton = uibutton(app.UIFigure, 'push');
            app.NewImageButton.ButtonPushedFcn = createCallbackFcn(app, @NewImageButtonPushed, true);
            app.NewImageButton.Text = 'New Image';
            app.ConversionLabel = uilabel(app.UIFigure);
            app.ConversionLabel.Text = 'Conversion:';
            app.TrainedConversionButton = uibutton(app.UIFigure, 'push');
            app.TrainedConversionButton.Text = 'Trained Conversion';
            app.UntrainedConversionButton = uibutton(app.UIFigure, 'push');
            app.UntrainedConversionButton.Text = 'Untrained Conversion ';
            app.SwitchLabel = uilabel(app.UIFigure);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.UIFigure, 'toggle');
        end
    end

    methods (Access = public)

        % Construct app
        function app = app2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end